classdef app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        EditFieldLabel                  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        TextAreaLabel                   matlab.ui.control.Label
        TextArea                        matlab.ui.control.TextArea
        COMPortsLabel                   matlab.ui.control.Label
        COMports                        matlab.ui.control.DropDown
        ConnectButton                   matlab.ui.control.Button
        EnterlogfilenameEditFieldLabel  matlab.ui.control.Label
        EnterlogfilenameEditField       matlab.ui.control.EditField
        ShowrunningplotsCheckBox        matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        COMstate = false;   % Connection state of COM port
        COMconnection       % Instance of COM connection
        LOGstate = false;   % Is reading enabled
        COMreserved = false % Mutex for uart
        sendflag = false;
        command = "";
        filename = "initfname";
        logsfolder = '.\logs\';% log folder
        showplots = false;
        w = [];                 % rotations array
        v = [];                 % vehicle speed array
        cl_pos = [];            % clutch position array
        cl_sp = [];             % clutch speed array
        br_pos = [];            % brake position array
        br_sp = [];             % brake speed array
        vref = [];                 % vref array

        delta_t = 0.05;         % time period
        t = -0.05;  
%         h1 = animatedline;      % animated line 1
%         h2 = animatedline;      % animated line 2
%         h3 = animatedline;      % animated line 3
%         h4 = animatedline;      % animated line 4
    end
    
    methods (Access = private)
        
        function logEntry(app, entry)
            if strcmp(app.TextArea.Value,'')
                app.TextArea.Value = entry;
            else
                app.TextArea.Value = [app.TextArea.Value(:)', entry];
%                 scroll(app.TextArea, 'bottom');
            end
        end
        
        function logger(app)
            while(app.LOGstate)
                
                if(app.sendflag)
                    app.COMreserved = true;
                    write(app.COMconnection, app.command, "uint8");     
                    app.sendflag = false;
                    app.COMreserved = false;
                end
                
                while(app.COMreserved)
                    continue
                end
                app.COMreserved = true;

                
                w_cur = read(app.COMconnection,1,"single");
                v_cur = read(app.COMconnection,1,"single");
                cl_pos_cur = read(app.COMconnection,1,"single");
                cl_sp_cur = read(app.COMconnection,1,"single");
                br_pos_cur = read(app.COMconnection,1,"single");
                br_sp_cur = read(app.COMconnection,1,"single");
                vref_cur = read(app.COMconnection,1,"single");
                
                app.COMreserved = false;
                
                if(~isempty(v_cur))
                    
                    % do something
                    t_var = app.t
                    app.w = [app.w w_cur];
                    app.v = [app.v v_cur];
                    app.cl_pos = [app.cl_pos cl_pos_cur];
                    app.cl_sp = [app.cl_sp cl_sp_cur];
                    app.br_pos = [app.br_pos br_pos_cur];
                    app.br_sp = [app.br_sp br_sp_cur];
                    app.vref = [app.vref vref_cur];
                    app.t = [app.t t_var(end)+app.delta_t];
                    
                    if (app.showplots)
                        
%                     subplot(4,1,1)
%                     addpoints(app.h1,t_var(end),double(w_cur));
%                     subplot(4,1,2)
%                     addpoints(app.h2,t_var(end),double(M_cur));
%                     subplot(4,1,3)
%                     addpoints(app.h3,t_var(end),double(acc_cur));
%                     subplot(4,1,4)
%                     addpoints(app.h4,t_var(end),double(v_cur));
                    
                    drawnow

                    end
                    
                end

%                 pause(0.02)
                
            end
        end
        
        function sendCommand(app, text)

%             while(app.COMreserved)
%                 continue
%             end
            app.command = text;
            app.sendflag = true;
            
%             app.COMreserved = true;
%             write(app.COMconnection, text, "uint8");
%             app.COMreserved = false;
            
        end
end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.COMports.Items = serialportlist;
        end

        % Value changed function: EditField
        function newEntryRead(app, event)
            value = app.EditField.Value;
            logEntry(app, value);
            sendCommand(app, value);
            app.EditField.Value = '';
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            if(app.COMstate)
                app.COMconnection = [];
                app.COMstate = false;
                app.ConnectButton.Text = 'Connect';
                app.LOGstate = false;
                app.COMstate = false;   % Connection state of COM port
                app.LOGstate = false;   % Is reading enabled
                app.COMreserved = false % Mutex for uart
                app.sendflag = false;
            else
                try
                    app.COMconnection = serialport(app.COMports.Value, 115200, "Timeout", 60);% 0.1
                    app.COMstate = true;
                    app.ConnectButton.Text = 'Disconnect';
                    sendCommand(app,1);%synch command
                    app.LOGstate = true;
                    logger(app);
                catch ME
                    logEntry(app, convertCharsToStrings(ME.identifier));
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.LOGstate = false;
            w = app.w;
            v = app.v;
            cl_pos = app.cl_pos;
            cl_sp = app.cl_sp;
            br_pos = app.br_pos;
            br_sp = app.br_sp;
            vref = app.vref;
            t = app.t;
            t = t(2:end);
            path = strcat(app.logsfolder,app.filename);
            save(path,"w","v","cl_pos","cl_sp","br_pos","br_sp","vref","t")
            delete(app)
            
        end

        % Value changed function: EnterlogfilenameEditField
        function EnterlogfilenameEditFieldValueChanged(app, event)
            app.filename = app.EnterlogfilenameEditField.Value;
            app.ConnectButton.Enable = true;
            
        end

        % Value changed function: ShowrunningplotsCheckBox
        function ShowrunningplotsCheckBoxValueChanged(app, event)
            app.showplots = app.ShowrunningplotsCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @newEntryRead, true);
            app.TextAreaLabel = uilabel(app.UIFigure);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.UIFigure);
            app.COMPortsLabel = uilabel(app.UIFigure);
            app.COMPortsLabel.Text = 'COM Ports';
            app.COMports = uidropdown(app.UIFigure);
            app.COMports.Items = {};
            app.COMports.Value = {};
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Enable = 'off';
            app.ConnectButton.Text = 'Connect';
            app.EnterlogfilenameEditFieldLabel = uilabel(app.UIFigure);
            app.EnterlogfilenameEditFieldLabel.Text = 'Enter log file name';
            app.EnterlogfilenameEditField = uieditfield(app.UIFigure, 'text');
            app.EnterlogfilenameEditField.ValueChangedFcn = createCallbackFcn(app, @EnterlogfilenameEditFieldValueChanged, true);
            app.ShowrunningplotsCheckBox = uicheckbox(app.UIFigure);
            app.ShowrunningplotsCheckBox.ValueChangedFcn = createCallbackFcn(app, @ShowrunningplotsCheckBoxValueChanged, true);
            app.ShowrunningplotsCheckBox.Text = 'Show running plots?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end